classdef graphplotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FunctionEditField       matlab.ui.control.EditField
        FunctionEditFieldLabel  matlab.ui.control.Label
        PlotButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           funcStr = app.FunctionEditField.Value;

            x = 0:0.01: (360/180) * pi;
            
            try
                y = eval(funcStr);
                plot(app.UIAxes, x, y);
                title(app.UIAxes, ['y = ' funcStr])
                xlabel(app.UIAxes, 'X')
                ylabel(app.UIAxes, 'Y')
            catch
                msgbox('Invalid function entered', 'Error', 'error');
            end
           
        end

        % Value changed function: FunctionEditField
        function FunctionEditFieldValueChanged(app, event)
            value = app.FunctionEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'y = sin(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphplotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end